classdef pf32 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        li35Label                      matlab.ui.control.Label
        li33scale005For20mmlensLabel   matlab.ui.control.Label
        MeasNameEditField              matlab.ui.control.EditField
        MeasNameEditFieldLabel         matlab.ui.control.Label
        Outputtotransformvertices_voltagematLabel  matlab.ui.control.Label
        ShowvoltagegridinferredButton  matlab.ui.control.Button
        ClearFigureButton              matlab.ui.control.Button
        ProgramStateEditField          matlab.ui.control.EditField
        ProgramStateEditFieldLabel     matlab.ui.control.Label
        ShowandsavevoltagegridinterpolationButton  matlab.ui.control.Button
        ShowgammamapButton             matlab.ui.control.Button
        ExposureEditField              matlab.ui.control.NumericEditField
        ExposureEditFieldLabel         matlab.ui.control.Label
        DopostprocessButton            matlab.ui.control.Button
        GeneratepremeasureButton       matlab.ui.control.Button
        edge_numEditField              matlab.ui.control.NumericEditField
        edge_numEditFieldLabel         matlab.ui.control.Label
        center_YEditField              matlab.ui.control.NumericEditField
        center_YEditFieldLabel         matlab.ui.control.Label
        center_XEditField              matlab.ui.control.NumericEditField
        center_XEditFieldLabel         matlab.ui.control.Label
        ScaleEditField_2               matlab.ui.control.NumericEditField
        ScaleEditField_2Label          matlab.ui.control.Label
        FindveticesvoltageButton       matlab.ui.control.Button
        ScanfourverticesButton         matlab.ui.control.Button
        ScaleEditField                 matlab.ui.control.NumericEditField
        ScaleEditFieldLabel            matlab.ui.control.Label
        XEditField_2                   matlab.ui.control.NumericEditField
        XEditField_8Label              matlab.ui.control.Label
        YEditField_2                   matlab.ui.control.NumericEditField
        YEditField_4Label              matlab.ui.control.Label
        XEditField_3                   matlab.ui.control.NumericEditField
        XEditField_7Label              matlab.ui.control.Label
        YEditField_3                   matlab.ui.control.NumericEditField
        YEditField_3Label              matlab.ui.control.Label
        XEditField_4                   matlab.ui.control.NumericEditField
        XEditField_6Label              matlab.ui.control.Label
        YEditField_4                   matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditField_5Label              matlab.ui.control.Label
        SetButton_4                    matlab.ui.control.Button
        SetButton_3                    matlab.ui.control.Button
        SetButton_2                    matlab.ui.control.Button
        voltagebottomrightLabel        matlab.ui.control.Label
        voltagetoprightLabel           matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        voltagebottomleftLabel         matlab.ui.control.Label
        YEditFieldLabel                matlab.ui.control.Label
        voltagetopleftLabel            matlab.ui.control.Label
        SetButton                      matlab.ui.control.Button
        LivestreamButton               matlab.ui.control.Button
        WallNameEditField              matlab.ui.control.EditField
        WallNameEditFieldLabel         matlab.ui.control.Label
        MeasPathEditField              matlab.ui.control.EditField
        MeasPathEditFieldLabel         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LivestreamButton
        function LivestreamButtonPushed(app, event)
            clearvars -GLOBAL series hax
            global series
            global hax %#ok<NUSED>

            OK = pf_getLibraryStatus;
            if(OK)
                % Close the library if it is already open. The scripts will load it
                % when needed.
                pf_close
            end
            [pf32, alias, report] = pf_open;

            pf_getLibraryStatus;
            pf_setNumberAccumulations(pf32, 20);
            pf_setExposure(pf32, 45); % microseconds
            global enablePositionalData
            enablePositionalData = 0;
            calllib(alias, 'setEnablePositionalData', pf32, enablePositionalData);
            pf_liveImageStream(pf32, 100000000);
            pf_close(pf32);
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            load('voltage.mat', 'Vx', 'Vy');
            app.XEditField.Value = Vx;
            app.YEditField.Value = Vy;
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
            load('voltage.mat', 'Vx', 'Vy');
            app.XEditField_2.Value = Vx;
            app.YEditField_2.Value = Vy;
        end

        % Button pushed function: SetButton_3
        function SetButton_3Pushed(app, event)
            load('voltage.mat', 'Vx', 'Vy');
            app.XEditField_3.Value = Vx;
            app.YEditField_3.Value = Vy;
        end

        % Button pushed function: SetButton_4
        function SetButton_4Pushed(app, event)
            load('voltage.mat', 'Vx', 'Vy');
            app.XEditField_4.Value = Vx;
            app.YEditField_4.Value = Vy;
        end

        % Button pushed function: ScanfourverticesButton
        function ScanfourverticesButtonPushed(app, event)
            % Scan region around the four vertices, 
            % generate 2*4096 matrix 
            % output: voltage_x, voltage_y, row, col for all pixels
            % scanning, and make running_flag be 1. After scanning, we will
            % have the four peak with its voltage_x and voltage_y, using
            % them, generate a voltage grid

            app.ProgramStateEditField.Value = 'PROCESSING!';
            input_path = 'C:\Users\74338\Desktop\IQI\resources\input\';
            scale = app.ScaleEditField.Value;
            [voltage_x1, voltage_y1] = genV(app.XEditField.Value - scale, ...
                                            app.XEditField.Value + scale, ...
                                            app.YEditField.Value - scale, ...
                                            app.YEditField.Value + scale, 32, 32);
            [voltage_x2, voltage_y2] = genV(app.XEditField_2.Value - scale, ...
                                            app.XEditField_2.Value + scale, ...
                                            app.YEditField_2.Value - scale, ...
                                            app.YEditField_2.Value + scale, 32, 32);
            [voltage_x3, voltage_y3] = genV(app.XEditField_3.Value - scale, ...
                                            app.XEditField_3.Value + scale, ...
                                            app.YEditField_3.Value - scale, ...
                                            app.YEditField_3.Value + scale, 32, 32);
            [voltage_x4, voltage_y4] = genV(app.XEditField_4.Value - scale, ...
                                            app.XEditField_4.Value + scale, ...
                                            app.YEditField_4.Value - scale, ...
                                            app.YEditField_4.Value + scale, 32, 32);
            % (2, 4096)
            voltage_x = [voltage_x1, voltage_x2, voltage_x3, voltage_x4];
            voltage_y = [voltage_y1, voltage_y2, voltage_y3, voltage_y4];
            row = zeros(1, 4096);
            col = zeros(1, 4096);

            row(1, 1:1024) = 1;            
            col(1, 1:1024) = 1;

            row(1, 1025:2048) = 32;
            col(1, 1025:2048) = 1;
            
            row(1, 2049:3072) = 32;
            col(1, 2049:3072) = 32;
            
            row(1, 3073:4096) = 1;
            col(1, 3073:4096) = 32;

            exposure = 500;
            save(strcat(input_path, 'input_params_cali_vertex.mat'), 'voltage_x', 'voltage_y', 'row', 'col', 'exposure', '-v7.3');
            
            app.ProgramStateEditField.Value = 'READY!';
            
        end

        % Button pushed function: FindveticesvoltageButton
        function FindveticesvoltageButtonPushed(app, event)
            app.ProgramStateEditField.Value = 'PROCESSING!';
            resource_path = 'C:\Users\74338\Desktop\IQI\resources\';
            output_path = 'C:\Users\74338\Desktop\IQI\PF32_py\transforms\';
            % After scanning four vertices, the data is (4096, 1024),
            % firstly separate it into 4 * (1024, 1024), then for each of
            % them, find the max value in each histogram.
            load(strcat(resource_path, 'output\data_cali_vertex.mat'), 'data');
            
            % Allocate memory for all peaks in all histograms.
            max_peak_index = zeros(4);
            for i=1:4
                peaks = max(data((1024*i-1023):(1024*i), :), [], 2); % size = (1024, 1)
                [~, max_peak_index(i)] = max(peaks);
                max_peak_index(i) = max_peak_index(i) + 1024*i - 1024;
            end
           
            
            % Load the voltage parameters in the cali input to find the
            % voltages at which the peaks are maximized.
            load(strcat(resource_path, 'input\input_params_cali_vertex.mat'), 'voltage_x', 'voltage_y');
            vertices_voltage = zeros(2, 4);
            
            for i=1:4
                vertices_voltage(1, i) = voltage_x(max_peak_index(i));
                vertices_voltage(2, i) = voltage_y(max_peak_index(i));
            end
            
            save(strcat(output_path, app.WallNameEditField.Value, '.mat'), 'vertices_voltage');
            
            app.ProgramStateEditField.Value = 'READY!';
        end

        % Button pushed function: GeneratepremeasureButton
        function GeneratepremeasureButtonPushed(app, event)
            app.ProgramStateEditField.Value = 'PROCESSING!';
            if isempty(app.MeasNameEditField.Value)
                app.ProgramStateEditField.Value = 'TYPE MEAS NAME!';
            else
                exposure = app.ExposureEditField.Value;
                edge_num = app.edge_numEditField.Value;
                voltage_x_origin = app.center_XEditField.Value;
                voltage_y_origin = app.center_YEditField.Value;
                scale = app.ScaleEditField_2.Value;
                save(strcat('C:\Users\74338\Desktop\IQI\PF32_py\transforms\', app.MeasNameEditField.Value, "_pre_meas.mat"),'exposure', ...
                    'edge_num','voltage_x_origin','voltage_y_origin', 'scale');
                app.ProgramStateEditField.Value = 'READY!';
            end
            
        end

        % Button pushed function: DopostprocessButton
        function DopostprocessButtonPushed(app, event)
            app.ProgramStateEditField.Value = 'PROCESSING!';
            if isempty(app.MeasNameEditField.Value) || isempty(app.WallNameEditField.Value)
                app.ProgramStateEditField.Value = 'TYPE MEAS NAME!';
            else
                load('C:\Users\74338\Desktop\IQI\resources\bad_indices.mat', 'bad_indices'); % (32, 32, 1024)
                resource_path = 'C:\Users\74338\Desktop\IQI\resources\';
                data_path = strcat(resource_path, "output\");
    
                % Size of data is (num_of_points, 32, 32, 1024)
                load(strcat(data_path, "data_meas.mat"), 'data');

                % Still need positions_laser and positions_receive
                load(strcat(app.MeasPathEditField.Value, '\positions_laser.mat'), 'positions_laser'); % (2, num_of_points)
                load(strcat(app.MeasPathEditField.Value, '\positions_receive.mat'), 'positions_receive'); % (2, 1024)
    
                % Need galvanometer's coordinates
                load(strcat('C:\Users\74338\Desktop\IQI\PF32_py\transforms\', app.WallNameEditField.Value, '_galva.mat'), 'coordinates_galva')
    
                num_of_points = size(data, 1);

                for i=1:num_of_points
                    data(i, :, :, :) = squeeze(data(i, :, :, :)) .* bad_indices;
                end

                coordinates_receive = [positions_receive; zeros(1, 1024)];
                coordinates_grid = reshape(coordinates_receive, 3, 32, 32);
                
                % Unroll the data to match eventual shape
                load(strcat(app.MeasPathEditField.Value, '\peak_grid.mat'), 'peak_grid'); % (32, 32)
    
                % Shift peak grid
                peak_grid_shifted = zeros(num_of_points, 32, 32);
                
                for i=1:num_of_points
                    temp_coords_laser = [positions_laser(:, i); 0];
                    for j=1:32
                        for k=1:32
                            temp_coords_receive = coordinates_grid(:, j, k);
                            distance = sqrt(sum((temp_coords_laser-coordinates_galva).^2)) - sqrt(sum((temp_coords_receive-coordinates_galva).^2));
                            num_bins_miss = floor(1000 * distance / 0.3 / 55);
                            peak_grid_shifted(i, j, k) = peak_grid(j, k) + num_bins_miss; 
                        end
                    end
                end
                

                enhanced_data = shift_and_enhance(data, peak_grid_shifted);
    
                % Merge 1024 pixels
                enhanced_data = reshape(enhanced_data, num_of_points, 1024, 1024);
                
                % Merge num_of_points frames
                enhanced_data = reshape(enhanced_data, [], 1024);
                
    
                positions_receive = zeros(2, num_of_points, 32, 32);
                
                % positions_receive = (2, num_of_points, 32, 32)
                for i=1:num_of_points
                    positions_receive(:, i, :, :) = coordinates_grid(1:2, :, :);
                end
                temp_positions_laser = zeros(2, num_of_points, 32, 32);
                for i=1:num_of_points
                    for j=1:32
                        for k=1:32
                            temp_positions_laser(:, i, j, k) = positions_laser(:, i);
                        end
                    end
                end
                
                positions_receive = reshape(positions_receive, 2, num_of_points * 1024);
                positions_laser = reshape(temp_positions_laser, 2, num_of_points * 1024);
                enhanced_data(:, 700:1024) = 0;
    
                save(strcat(app.MeasPathEditField.Value, '\enhanced_data.mat'), 'enhanced_data', 'positions_laser', 'positions_receive', '-v7.3');
                bar(app.UIAxes, sum(enhanced_data, 1));
                app.ProgramStateEditField.Value = 'READY!';
            end
        end

        % Button pushed function: ShowandsavevoltagegridinterpolationButton
        function ShowandsavevoltagegridinterpolationButtonPushed(app, event)
            app.ProgramStateEditField.Value = 'PROCESSING!';
            transforms_path = 'C:\Users\74338\Desktop\IQI\PF32_py\transforms\';
            input_path = 'C:\Users\74338\Desktop\IQI\resources\input\';
            if isempty(app.WallNameEditField.Value)
                app.ProgramStateEditField.Value = 'TYPE VIDEO NAME!';
            else
                load(strcat(transforms_path, app.WallNameEditField.Value, '.mat'), 'vertices_voltage');
                % Create linear edge space according to the left edge and right
                % edge.
                left_edge_x = linspace(vertices_voltage(1, 1), vertices_voltage(1, 2), 32);
                left_edge_y = linspace(vertices_voltage(2, 1), vertices_voltage(2, 2), 32);
                
                right_edge_x = linspace(vertices_voltage(1, 4), vertices_voltage(1, 3), 32);
                right_edge_y = linspace(vertices_voltage(2, 4), vertices_voltage(2, 3), 32);
            
                voltage_x = zeros(32, 32);
                voltage_y = zeros(32, 32);
                row = zeros(1024, 1);
                col = zeros(1024, 1);
                for i=1:32
                    [temp_grid_x, temp_grid_y] = meshgrid(linspace(left_edge_x(i), right_edge_x(i), 32), ...
                                                          linspace(left_edge_y(i), right_edge_y(i), 32));
                    for j=1:32
                        voltage_x(i, j) = temp_grid_x(j, j);
                        voltage_y(i, j) = temp_grid_y(j, j);
                        row((i-1)*32+j) = j;
                        col((i-1)*32+j) = i;
                    end
                end
                
                % Coordinates_grid for the measurement result, providing
                % receive positions.
                voltage_x = reshape(voltage_x, 1, 1024);
                voltage_y = reshape(voltage_y, 1, 1024);
                exposure = 200;
                scatter(app.UIAxes, voltage_x, voltage_y);
                hold(app.UIAxes, 'on');
                save(strcat(transforms_path, app.WallNameEditField.Value, '_voltage_grid_interpolated.mat'), 'voltage_x', 'voltage_y');
                save(strcat(input_path, 'input_params_cali_all_pixels.mat'), 'voltage_x', 'voltage_y', 'row', 'col', 'exposure');
                app.ProgramStateEditField.Value = 'READY!';
                
            end
            
        end

        % Button pushed function: ShowgammamapButton
        function ShowgammamapButtonPushed(app, event)
            % After scanning all the pixels, calculate the peak values and
            % indices.
            if isempty(app.MeasPathEditField.Value)
                app.ProgramStateEditField.Value = 'TYPE MEASURE PATH!';
            else
            data_path = 'C:\Users\74338\Desktop\IQI\resources\output\';
            
            % Now the data is (1024, 1024), containing 1024 histograms
            load(strcat(data_path, 'data_all_pixels.mat'), 'data');
            
            % Peak grid for data enhancement, gamma map for visualizing the
            % calibration result
            
            [gamma_map, peak_grid] = max(data, [], 2);
            peak_grid = reshape(peak_grid, 32, 32);
            save(strcat(app.MeasPathEditField.Value, '\peak_grid.mat'), 'peak_grid', '-v7.3');
            gamma_map = reshape(gamma_map, 32, 32);
            imshow(gamma_map, [], 'Parent', app.UIAxes);
            end
        end

        % Button pushed function: ClearFigureButton
        function ClearFigureButtonPushed(app, event)
            cla(app.UIAxes, 'reset');
        end

        % Button pushed function: ShowvoltagegridinferredButton
        function ShowvoltagegridinferredButtonPushed(app, event)
            app.ProgramStateEditField.Value = 'PROCESSING!';
            if isempty(app.WallNameEditField.Value)
                app.ProgramStateEditField.Value = 'TYPE VIDEO NAME!';
            else
                row = zeros(1024, 1);
                col = zeros(1024, 1);
                load(strcat('C:\Users\74338\Desktop\IQI\PF32_py\transforms\', app.WallNameEditField.Value, '_pixels_cali_result.mat'), 'voltage_x', 'voltage_y', 'coords');
                coords = reshape(coords, 2, []);
                scatter(app.UIAxes, coords(1,:), coords(2,:))
                app.ProgramStateEditField.Value = 'READY!';
                for i=1:32
                    for j=1:32
                        row((i-1)*32+j) = j;
                        col((i-1)*32+j) = i;
                    end
                end
                exposure = 200;
                save(strcat(input_path, 'input_params_cali_all_pixels.mat'), 'voltage_x', 'voltage_y', 'row', 'col', 'exposure');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.MeasPathEditFieldLabel = uilabel(app.UIFigure);
            app.MeasPathEditFieldLabel.Text = 'Meas Path';
            app.MeasPathEditField = uieditfield(app.UIFigure, 'text');
            app.WallNameEditFieldLabel = uilabel(app.UIFigure);
            app.WallNameEditFieldLabel.Text = 'Wall Name';
            app.WallNameEditField = uieditfield(app.UIFigure, 'text');
            app.LivestreamButton = uibutton(app.UIFigure, 'push');
            app.LivestreamButton.ButtonPushedFcn = createCallbackFcn(app, @LivestreamButtonPushed, true);
            app.LivestreamButton.Text = 'Livestream';
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.voltagetopleftLabel = uilabel(app.UIFigure);
            app.voltagetopleftLabel.Interpreter = 'html';
            app.voltagetopleftLabel.Text = 'voltage top left';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.voltagebottomleftLabel = uilabel(app.UIFigure);
            app.voltagebottomleftLabel.Interpreter = 'html';
            app.voltagebottomleftLabel.Text = 'voltage bottom left';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.voltagetoprightLabel = uilabel(app.UIFigure);
            app.voltagetoprightLabel.Interpreter = 'html';
            app.voltagetoprightLabel.Text = 'voltage top right';
            app.voltagebottomrightLabel = uilabel(app.UIFigure);
            app.voltagebottomrightLabel.Interpreter = 'html';
            app.voltagebottomrightLabel.Text = 'voltage bottom right';
            app.SetButton_2 = uibutton(app.UIFigure, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Text = 'Set';
            app.SetButton_3 = uibutton(app.UIFigure, 'push');
            app.SetButton_3.ButtonPushedFcn = createCallbackFcn(app, @SetButton_3Pushed, true);
            app.SetButton_3.Text = 'Set';
            app.SetButton_4 = uibutton(app.UIFigure, 'push');
            app.SetButton_4.ButtonPushedFcn = createCallbackFcn(app, @SetButton_4Pushed, true);
            app.SetButton_4.Text = 'Set';
            app.XEditField_5Label = uilabel(app.UIFigure);
            app.XEditField_5Label.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_6Label = uilabel(app.UIFigure);
            app.XEditField_6Label.Text = 'X';
            app.XEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_3Label = uilabel(app.UIFigure);
            app.YEditField_3Label.Text = 'Y';
            app.YEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_7Label = uilabel(app.UIFigure);
            app.XEditField_7Label.Text = 'X';
            app.XEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_4Label = uilabel(app.UIFigure);
            app.YEditField_4Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_8Label = uilabel(app.UIFigure);
            app.XEditField_8Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleEditFieldLabel.Interpreter = 'html';
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScanfourverticesButton = uibutton(app.UIFigure, 'push');
            app.ScanfourverticesButton.ButtonPushedFcn = createCallbackFcn(app, @ScanfourverticesButtonPushed, true);
            app.ScanfourverticesButton.Text = 'Scan four vertices';
            app.FindveticesvoltageButton = uibutton(app.UIFigure, 'push');
            app.FindveticesvoltageButton.ButtonPushedFcn = createCallbackFcn(app, @FindveticesvoltageButtonPushed, true);
            app.FindveticesvoltageButton.Text = 'Find vetices voltage';
            app.ScaleEditField_2Label = uilabel(app.UIFigure);
            app.ScaleEditField_2Label.Interpreter = 'html';
            app.ScaleEditField_2Label.Text = 'Scale';
            app.ScaleEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.center_XEditFieldLabel = uilabel(app.UIFigure);
            app.center_XEditFieldLabel.Interpreter = 'html';
            app.center_XEditFieldLabel.Text = 'center_X';
            app.center_XEditField = uieditfield(app.UIFigure, 'numeric');
            app.center_YEditFieldLabel = uilabel(app.UIFigure);
            app.center_YEditFieldLabel.Interpreter = 'html';
            app.center_YEditFieldLabel.Text = 'center_Y';
            app.center_YEditField = uieditfield(app.UIFigure, 'numeric');
            app.edge_numEditFieldLabel = uilabel(app.UIFigure);
            app.edge_numEditFieldLabel.Interpreter = 'html';
            app.edge_numEditFieldLabel.Text = 'edge_num';
            app.edge_numEditField = uieditfield(app.UIFigure, 'numeric');
            app.GeneratepremeasureButton = uibutton(app.UIFigure, 'push');
            app.GeneratepremeasureButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratepremeasureButtonPushed, true);
            app.GeneratepremeasureButton.Text = 'Generate pre-measure';
            app.DopostprocessButton = uibutton(app.UIFigure, 'push');
            app.DopostprocessButton.ButtonPushedFcn = createCallbackFcn(app, @DopostprocessButtonPushed, true);
            app.DopostprocessButton.Text = 'Do postprocess';
            app.ExposureEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureEditFieldLabel.Interpreter = 'html';
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShowgammamapButton = uibutton(app.UIFigure, 'push');
            app.ShowgammamapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowgammamapButtonPushed, true);
            app.ShowgammamapButton.Text = 'Show gamma map';
            app.ShowandsavevoltagegridinterpolationButton = uibutton(app.UIFigure, 'push');
            app.ShowandsavevoltagegridinterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowandsavevoltagegridinterpolationButtonPushed, true);
            app.ShowandsavevoltagegridinterpolationButton.Text = 'Show and save voltage grid (interpolation)';
            app.ProgramStateEditFieldLabel = uilabel(app.UIFigure);
            app.ProgramStateEditFieldLabel.Interpreter = 'html';
            app.ProgramStateEditFieldLabel.Text = 'Program State';
            app.ProgramStateEditField = uieditfield(app.UIFigure, 'text');
            app.ClearFigureButton = uibutton(app.UIFigure, 'push');
            app.ClearFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureButtonPushed, true);
            app.ClearFigureButton.Text = 'Clear Figure';
            app.ShowvoltagegridinferredButton = uibutton(app.UIFigure, 'push');
            app.ShowvoltagegridinferredButton.ButtonPushedFcn = createCallbackFcn(app, @ShowvoltagegridinferredButtonPushed, true);
            app.ShowvoltagegridinferredButton.Text = 'Show voltage grid (inferred)';
            app.Outputtotransformvertices_voltagematLabel = uilabel(app.UIFigure);
            app.Outputtotransformvertices_voltagematLabel.Interpreter = 'html';
            app.Outputtotransformvertices_voltagematLabel.Text = 'Output to transform/vertices_voltage.mat';
            app.MeasNameEditFieldLabel = uilabel(app.UIFigure);
            app.MeasNameEditFieldLabel.Text = 'Meas Name';
            app.MeasNameEditField = uieditfield(app.UIFigure, 'text');
            app.li33scale005For20mmlensLabel = uilabel(app.UIFigure);
            app.li33scale005For20mmlensLabel.Interpreter = 'html';
            app.li33scale005For20mmlensLabel.Text = {'li=3.3,scale=0.05'; 'For 20mm lens'};
            app.li35Label = uilabel(app.UIFigure);
            app.li35Label.Interpreter = 'html';
            app.li35Label.Text = 'li=3.5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pf32

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end